classdef burst_mod_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        btn_reset          matlab.ui.control.Button
        sbtn_combine_next  matlab.ui.control.StateButton
        sbtn_edit_end      matlab.ui.control.StateButton
        sbtn_edit_start    matlab.ui.control.StateButton
        sbtn_combine_prev  matlab.ui.control.StateButton
        btn_cancel         matlab.ui.control.Button
        btn_confirm        matlab.ui.control.Button
        ax                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        data
        caller
        settings
    end
    
    methods (Access = private)
        
        function update_ax(app)
            cla(app.ax)
            hold (app.ax, "on")
            
            plot (app.ax, app.data.ts, app.data.x,LineWidth=1.5,HitTest='off')
            plot (app.ax, app.data.ts, app.data.x_filt,LineWidth=2,HitTest="off")
            if app.sbtn_combine_next.Value || app.sbtn_combine_prev.Value
                if app.sbtn_combine_next.Value
                    plot(app.ax, app.data.ts(app.settings.burst_loc_new(1):min(length(app.data.x),app.settings.burst_loc(3,2))), app.data.x(app.settings.burst_loc_new(1):min(length(app.data.x),app.settings.burst_loc(3,2))),'m',LineWidth=2,HitTest='off')
                    plot(app.ax, app.data.ts(max(1,app.settings.burst_loc(1,1)):app.settings.burst_loc(1,2)), app.data.x(max(1,app.settings.burst_loc(1,1)):app.settings.burst_loc(1,2)),'g',LineWidth=2,HitTest='off')
                else
                    plot(app.ax, app.data.ts(max(1,app.settings.burst_loc(1,1)):app.settings.burst_loc_new(2)), app.data.x(max(1,app.settings.burst_loc(1,1)):app.settings.burst_loc_new(2)),'m',LineWidth=2,HitTest='off')
                    plot(app.ax, app.data.ts(app.settings.burst_loc(3,1):min(length(app.data.x),app.settings.burst_loc(3,2))), app.data.x(app.settings.burst_loc(3,1):min(length(app.data.x),app.settings.burst_loc(3,2))),'r',LineWidth=2,HitTest='off')
                end

            else
                if ~isnan(app.settings.burst_loc_new(1)) & ~isnan(app.settings.burst_loc_new(2))
                    plot(app.ax, app.data.ts(app.settings.burst_loc_new(1):app.settings.burst_loc_new(2)), app.data.x(app.settings.burst_loc_new(1):app.settings.burst_loc_new(2)),'m',LineWidth=2,HitTest='off')
                else
                    if ~isnan (app.settings.burst_loc_new(1))
                        plot(app.ax, app.data.ts(app.settings.burst_loc_new(1)), app.data.x(app.settings.burst_loc_new(1)),'>m',MarkerSize=10,MarkerFaceColor='m',LineWidth=1,HitTest='off')
                    end
                    if ~isnan(app.settings.burst_loc_new(2))
                        plot(app.ax, app.data.ts(app.settings.burst_loc_new(2)), app.data.x(app.settings.burst_loc_new(2)),'<m',MarkerSize=10,MarkerFaceColor='m',LineWidth=1,HitTest='off')
                    end
                end
                plot(app.ax, app.data.ts(max(1,app.settings.burst_loc(1,1)):app.settings.burst_loc(1,2)), app.data.x(max(1,app.settings.burst_loc(1,1)):app.settings.burst_loc(1,2)),'g',LineWidth=3,HitTest='off')
                plot(app.ax, app.data.ts(app.settings.burst_loc(3,1):min(length(app.data.x),app.settings.burst_loc(3,2))), app.data.x(app.settings.burst_loc(3,1):min(length(app.data.x),app.settings.burst_loc(3,2))),'r',LineWidth=3,HitTest='off')
            end
            
            plot (app.ax, app.data.ts(app.data.mns), app.data.x_filt(app.data.mns), '*k',MarkerSize=10,LineWidth=1,HitTest='off')
            xlim(app.ax,[app.data.ts(1), app.data.ts(end)])
            hold (app.ax, "off")
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, pos)
            app.caller  = caller;
            app.data.x = app.caller.burst_res.x;
            app.data.fs = 1/app.caller.data(app.caller.settings.channel_idx.msna).ts(1);
            [b,a] = butter(2,[0.05,4]/(app.data.fs/2),"bandpass"); 
            
            app.data.x_filt = filtfilt(b,a,app.data.x); %%filtfilt(b,a,tmp_data);
            app.data.diff_x_filt = diff(app.data.x_filt).*circshift(diff(app.data.x_filt),1);
            app.data.ts = app.caller.burst_res.ts(1):app.caller.burst_res.ts(2):app.caller.burst_res.ts(3);
            idx = find(app.data.ts>= pos,1);
            app.settings.plot_idx = [idx-3*app.data.fs; idx+3*app.data.fs];
            brst_idx = find(app.caller.burst_res.burst_loc(:,1)<idx & app.caller.burst_res.burst_loc(:,2)>idx);
            if ~isempty(brst_idx)
                app.settings.burst_idx(1) = brst_idx-1;
                app.settings.burst_loc(1,:) = app.caller.burst_res.burst_loc(brst_idx-1,:);
                if app.settings.burst_loc(1,2)<app.settings.plot_idx(1)
                    app.settings.burst_idx(1) = nan;
                    app.settings.burst_loc(1,:) = [nan,nan];
                    app.sbtn_combine_prev.Enable ="off";
                else
                    app.settings.burst_loc(1,:) = app.settings.burst_loc(1,:)-app.settings.plot_idx(1)+1;
                    app.sbtn_combine_prev.Enable ="on";
                end
                
                app.settings.burst_idx(2) = brst_idx;
                app.settings.burst_loc(2,:) = app.caller.burst_res.burst_loc(brst_idx,:)-app.settings.plot_idx(1)+1;
                app.settings.burst_loc_new = app.caller.burst_res.burst_loc(brst_idx,:)-app.settings.plot_idx(1)+1;
                app.settings.burst_idx(3) = brst_idx+1;
                app.settings.burst_loc(3,:) = app.caller.burst_res.burst_loc(brst_idx+1,:);
                if app.settings.burst_loc(3,1)>app.settings.plot_idx(2)
                    app.settings.burst_idx(3) = nan;
                    app.settings.burst_loc(3,:) = [nan,nan];
                    app.sbtn_combine_next.Enable = "off";
                else
                    app.settings.burst_loc(3,:) = app.settings.burst_loc(3,:)-app.settings.plot_idx(1)+1;
                    app.sbtn_combine_next.Enable = "on";
                end
            else
                app.settings.burst_idx(1) = find(app.caller.burst_res.burst_loc(:,2)<idx,1,'last');
                app.settings.burst_loc(1,:) = app.caller.burst_res.burst_loc(app.settings.burst_idx(1),:);
                if app.settings.burst_loc(1,2)<app.settings.plot_idx(1)
                    app.settings.burst_idx(1) = nan;
                    app.settings.burst_loc(1,:) = [nan,nan];
                    app.sbtn_combine_prev.Enable ="off";
                else
                    app.settings.burst_loc(1,:) = app.settings.burst_loc(1,:)-app.settings.plot_idx(1)+1;
                    app.sbtn_combine_prev.Enable ="on";
                end
                app.settings.burst_idx(2) = nan;
                app.settings.burst_loc(2,:) = [nan,nan];
                app.settings.burst_loc_new = [nan,nan];
                app.settings.burst_idx(3) = find(app.caller.burst_res.burst_loc(:,1)>idx,1,'first');
                app.settings.burst_loc(3,:) = app.caller.burst_res.burst_loc(app.settings.burst_idx(3),:);
                if app.settings.burst_loc(3,1)>app.settings.plot_idx(2)
                    app.settings.burst_idx(3) = nan;
                    app.settings.burst_loc(3,:) = [nan,nan];
                    app.sbtn_combine_next.Enable = "off";
                else
                    app.settings.burst_loc(3,:) = app.settings.burst_loc(3,:)-app.settings.plot_idx(1)+1;
                    app.sbtn_combine_next.Enable = "on";
                end
            end
            
            app.data.ts = app.data.ts(app.settings.plot_idx(1):app.settings.plot_idx(2));
            app.data.x = app.data.x(app.settings.plot_idx(1):app.settings.plot_idx(2));
            app.data.x_filt = app.data.x_filt(app.settings.plot_idx(1):app.settings.plot_idx(2));
            app.data.diff_x_filt = app.data.diff_x_filt(app.settings.plot_idx(1):app.settings.plot_idx(2));
            app.data.mns = find(app.data.diff_x_filt<0);
            if app.data.mns(1) == 1
                app.data.mns(1) = [];
            end
            tmp = diff(app.data.x_filt);
            rem = find(tmp(app.data.mns-1)>0);
            app.data.mns(rem) = [];
            extremes = [min(app.data.x),max(app.data.x)];
            app.data.x_filt = app.data.x_filt-min(app.data.x_filt);
            app.data.x_filt = app.data.x_filt/max(app.data.x_filt)*diff(extremes);
            app.data.x_filt = app.data.x_filt+extremes(1);
            update_ax(app)
        end

        % Value changed function: sbtn_combine_prev
        function sbtn_combine_prevValueChanged(app, event)
            app.sbtn_combine_next.Value = false;
            app.sbtn_edit_start.Value = false;
            app.sbtn_edit_end.Value = false;
            update_ax(app)
        end

        % Value changed function: sbtn_edit_start
        function sbtn_edit_startValueChanged(app, event)
            app.sbtn_combine_next.Value = false;
            app.sbtn_combine_prev.Value = false;
            app.sbtn_edit_end.Value = false;
            update_ax(app)
            
        end

        % Value changed function: sbtn_edit_end
        function sbtn_edit_endValueChanged(app, event)
            app.sbtn_combine_next.Value = false;
            app.sbtn_combine_prev.Value = false;
            app.sbtn_edit_start.Value = false;
            update_ax(app)
            
        end

        % Value changed function: sbtn_combine_next
        function sbtn_combine_nextValueChanged(app, event)
            app.sbtn_combine_prev.Value = false;
            app.sbtn_edit_end.Value = false;
            app.sbtn_edit_start.Value = false;
            update_ax(app)
            
        end

        % Button pushed function: btn_cancel
        function btn_cancelButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: btn_reset
        function btn_resetButtonPushed(app, event)
            app.settings.burst_loc_new = app.settings.burst_loc(2,:);
            app.sbtn_combine_next.Value = false;
            app.sbtn_combine_prev.Value = false;
            app.sbtn_edit_start.Value = false;
            app.sbtn_edit_end.Value = false;
            update_ax(app)
        end

        % Button pushed function: btn_confirm
        function btn_confirmButtonPushed(app, event)
            ts = app.caller.burst_res.ts(1):app.caller.burst_res.ts(2):app.caller.burst_res.ts(3);
            burst_res = app.caller.burst_res;
            hb_ts = app.caller.hb_res.t_events;

            if ~isnan(app.settings.burst_idx(2))
                if ~app.sbtn_combine_prev.Value & ~app.sbtn_combine_next.Value
                    to_edit = app.settings.burst_idx(2);
                    to_delete = nan;  
                    burst_loc = app.settings.burst_loc_new +app.settings.plot_idx(1)-1;
                    insert_burst = false;
                else
                    if app.sbtn_combine_prev.Value
                        to_edit = app.settings.burst_idx(2);
                        to_delete = app.settings.burst_idx(1); 
                        burst_loc = [app.settings.burst_loc(1,1),app.settings.burst_loc(2,2)]+app.settings.plot_idx(1)-1;
                        insert_burst = false;    
                    else
                        to_edit = app.settings.burst_idx(2);
                        to_delete = app.settings.burst_idx(3);
                        burst_loc = [app.settings.burst_loc(2,1),app.settings.burst_loc(3,2)]+app.settings.plot_idx(1)-1;
                        insert_burst = false;
                    end

                end

            else
                    to_edit = length(burst_res.t_burst)+1;
                    to_delete = nan;
                    burst_loc = app.settings.burst_loc_new +app.settings.plot_idx(1)-1;
                    insert_burst = true;
            end
            tmp_d = [ts(burst_loc(1):burst_loc(2))',burst_res.x(burst_loc(1):burst_loc(2))'*1000];
            tmp_d(:,2) = tmp_d(:,2) - min (tmp_d(:,2));

            [~, tmp] = max(tmp_d(:,2));

            idx_burst = tmp+burst_loc(1)-1;
            t_burst = ts(idx_burst);
            [~,hb_idx] = sort(abs(hb_ts-t_burst+app.caller.burst_res.delay),'ascend');
            brst_delay = t_burst -hb_ts(hb_idx(1));

            burst_res.burst_amp(to_edit)  = max(tmp_d(:,2)); %%% ampl
            burst_res.burst_int(to_edit) = trapz(tmp_d(:,1),tmp_d(:,2)); %% int
            burst_res.burst_loc(to_edit,:) = burst_loc;
            burst_res.t_burst(to_edit) = t_burst;
            burst_res.burst_dur(to_edit) = tmp_d(end,1)-tmp_d(1,1);
            burst_res.burst_latency(to_edit) = brst_delay;
            burst_res.use_burst(to_edit,1:2) = [true,true];
            if ~isnan(to_delete)
                burst_res.burst_amp(to_delete) = [];
                burst_res.burst_int(to_delete) = [];
                burst_res.burst_loc(to_delete,:) = [];
                burst_res.t_burst(to_delete) = [];
                burst_res.burst_dur(to_delete) = [];
                burst_res.burst_latency(to_delete) = [];
                burst_res.use_burst(to_delete,:) = [];
            end

            if insert_burst
                [~, sort_idx] = sort(burst_res.t_burst,'ascend');
                burst_res.burst_amp = burst_res.burst_amp(sort_idx);
                burst_res.burst_int = burst_res.burst_int(sort_idx);
                burst_res.burst_loc = burst_res.burst_loc(sort_idx,:);
                burst_res.t_burst = burst_res.t_burst(sort_idx);
                burst_res.burst_dur = burst_res.burst_dur(sort_idx);
                burst_res.burst_latency = burst_res.burst_latency(sort_idx);
                burst_res.use_burst = burst_res.use_burst(sort_idx,:);
            end

            import_bursts(app.caller,burst_res, true)
            delete(app)
        end

        % Button down function: ax
        function axButtonDown(app, event)
            if app.sbtn_edit_start.Value | app.sbtn_edit_end.Value
                idx = find(app.data.ts >= event.IntersectionPoint(1),1,'first');
                tmp = app.data.mns-idx;
                [~,mn_idx] = sort(abs(tmp),'ascend');
                mn_idx=mn_idx(1);

                if app.sbtn_edit_start.Value
                    if ~isnan(app.settings.burst_idx(1))
                        if app.data.mns(mn_idx)>=app.settings.burst_loc(1,2)
                            app.settings.burst_loc_new(1) = app.data.mns(mn_idx);
                        else
                            warndlg ('burst overlap')
                        end
                    end
                else
                    if ~isnan(app.settings.burst_idx(3))
                        if app.data.mns(mn_idx)<=app.settings.burst_loc(3,1)
                            app.settings.burst_loc_new(2) = app.data.mns(mn_idx);
                        else
                            warndlg ('burst overlap')
                        end
                    end
                end
                app.sbtn_edit_start.Value = false;
                app.sbtn_edit_end.Value = false;
                update_ax(app)
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ax = uiaxes(app.UIFigure);
            app.ax.YTick = [];
            app.ax.ButtonDownFcn = createCallbackFcn(app, @axButtonDown, true);
            app.btn_confirm = uibutton(app.UIFigure, 'push');
            app.btn_confirm.ButtonPushedFcn = createCallbackFcn(app, @btn_confirmButtonPushed, true);
            app.btn_confirm.Text = 'confirm';
            app.btn_cancel = uibutton(app.UIFigure, 'push');
            app.btn_cancel.ButtonPushedFcn = createCallbackFcn(app, @btn_cancelButtonPushed, true);
            app.btn_cancel.Text = 'cancel ';
            app.sbtn_combine_prev = uibutton(app.UIFigure, 'state');
            app.sbtn_combine_prev.ValueChangedFcn = createCallbackFcn(app, @sbtn_combine_prevValueChanged, true);
            app.sbtn_combine_prev.Text = 'combine with previous burst';
            app.sbtn_edit_start = uibutton(app.UIFigure, 'state');
            app.sbtn_edit_start.ValueChangedFcn = createCallbackFcn(app, @sbtn_edit_startValueChanged, true);
            app.sbtn_edit_start.Text = 'edit start';
            app.sbtn_edit_end = uibutton(app.UIFigure, 'state');
            app.sbtn_edit_end.ValueChangedFcn = createCallbackFcn(app, @sbtn_edit_endValueChanged, true);
            app.sbtn_edit_end.Text = 'edit end';
            app.sbtn_combine_next = uibutton(app.UIFigure, 'state');
            app.sbtn_combine_next.ValueChangedFcn = createCallbackFcn(app, @sbtn_combine_nextValueChanged, true);
            app.sbtn_combine_next.Text = 'combine with following burst';
            app.btn_reset = uibutton(app.UIFigure, 'push');
            app.btn_reset.ButtonPushedFcn = createCallbackFcn(app, @btn_resetButtonPushed, true);
            app.btn_reset.Text = 'reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = burst_mod_app(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end